classdef finalProject455 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BlurredMSE             matlab.ui.control.TextArea
        MSETextAreaLabel_2     matlab.ui.control.Label
        BlurredPSNR            matlab.ui.control.TextArea
        PSNRTextAreaLabel_2    matlab.ui.control.Label
        StepSizeSpinner        matlab.ui.control.Spinner
        StepSizeSpinnerLabel   matlab.ui.control.Label
        IterationSpinner       matlab.ui.control.Spinner
        IterationSpinnerLabel  matlab.ui.control.Label
        BlurButton             matlab.ui.control.Button
        BlurTypeButtonGroup    matlab.ui.container.ButtonGroup
        hsizeSpinner           matlab.ui.control.Spinner
        hsizeSpinnerLabel      matlab.ui.control.Label
        thetaSpinner           matlab.ui.control.Spinner
        thetaSpinnerLabel      matlab.ui.control.Label
        lengthSpinner          matlab.ui.control.Spinner
        lengthSpinnerLabel     matlab.ui.control.Label
        sigmaSpinner           matlab.ui.control.Spinner
        sigmaSpinnerLabel      matlab.ui.control.Label
        MotionBlurButton       matlab.ui.control.RadioButton
        GaussianButton         matlab.ui.control.RadioButton
        BlindDeconvolution_deblurButton  matlab.ui.control.Button
        BlindMSE               matlab.ui.control.TextArea
        MSETextArea_3Label     matlab.ui.control.Label
        LMMSE                  matlab.ui.control.TextArea
        MSETextArea_2Label     matlab.ui.control.Label
        WeinerMSE              matlab.ui.control.TextArea
        MSETextAreaLabel       matlab.ui.control.Label
        BlindPSNR              matlab.ui.control.TextArea
        PSNRTextArea_3Label    matlab.ui.control.Label
        LWPSNR                 matlab.ui.control.TextArea
        PSNRTextArea_2Label    matlab.ui.control.Label
        WeinerPSNR             matlab.ui.control.TextArea
        PSNRTextAreaLabel      matlab.ui.control.Label
        LM_deblurButton        matlab.ui.control.Button
        BlurredImg             matlab.ui.control.Image
        OrgImg                 matlab.ui.control.Image
        WNR_deblurButton       matlab.ui.control.Button
        BlindAxes              matlab.ui.control.UIAxes
        LMAxes                 matlab.ui.control.UIAxes
        WeinerAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          % Load the grayscale image
            grayscaleImage = imread('cameraman.tif');
            
            % Convert the grayscale image to an RGB image
            rgbImage = cat(3, grayscaleImage, grayscaleImage, grayscaleImage);
            
            % Set up original image
            app.OrgImg.ImageSource = rgbImage;


        end

        % Button pushed function: WNR_deblurButton
        function WNR_deblurButtonPushed(app, event)
            if app.GaussianButton.Value 
                sigma = app.sigmaSpinner.Value;
                hsize = app.hsizeSpinner.Value;
        
                % Perform deblurring 
                PSF = fspecial('motion',hsize,sigma);

            else
                theta = app.thetaSpinner.Value;
                length = app.lengthSpinner.Value;

                PSF = fspecial('motion',theta,length);
            end


            blurred_image = app.BlurredImg.ImageSource;
            deblurredImage = deconvwnr(blurred_image,PSF);

            imshow(deblurredImage, 'Parent', app.WeinerAxes);
            %title(app.WeinerAxes, 'Deblurred Image');
            
            originalImage = im2double(app.OrgImg.ImageSource);
            
            %calculate PSNR
            BlurImgPSNR = psnr(deblurredImage, originalImage);
            MSE = mean(mean(mean((deblurredImage - originalImage).^2)));
            %convert to number to display
            app.WeinerPSNR.Value = sprintf('%.2f', BlurImgPSNR);        
            app.WeinerMSE.Value = sprintf('%.5f', MSE);

        end

        % Button pushed function: LM_deblurButton
        function LM_deblurButtonPushed(app, event)
            I = im2double(imread('cameraman.tif'));
            % Get the blurred kernal
            if app.GaussianButton.Value
                hsize = app.hsizeSpinner.Value;
                sigma = app.sigmaSpinner.Value;
                PSF = fspecial('gaussian', hsize, sigma);
            else
                length = app.lengthSpinner.Value;
                theta = app.thetaSpinner.Value;
                PSF = fspecial('motion', length, theta); 
                [m,n] = size(PSF);
                if m > n
                    start = floor(m/2) - floor(n/2);
                    PSF = PSF(start:start + n-1,:);
                elseif m < n
                    start = floor(n/2) - floor(m/2);
                    PSF = PSF(:, start:start+m-1);
                end

               
            end
            % get parameter
            iter = app.IterationSpinner.Value;
            stepSize = app.StepSizeSpinner.Value;
            
            % LM deblurr
            if app.GaussianButton.Value
                blurredImg = app.BlurredImg.ImageSource;
            else
                blurredImg = imfilter(I, PSF, 'conv', 'circular');
            end
            deblurredImg = ALW(blurredImg, PSF, stepSize, iter);
       
            % Display deblurred img
            temp = imshow(deblurredImg, 'Parent', app.LMAxes, 'XData', [1 app.LMAxes.Position(3)], 'YData', [1 app.LMAxes.Position(4)]);
            app.LMAxes.XLim = [0 temp.XData(2)];
            app.LMAxes.YLim = [0 temp.YData(2)];

            % Calculate PSNR and MSE
            if app.MotionBlurButton.Value
                Orgimg = I;
            else
                Orgimg = im2double(app.OrgImg.ImageSource);
            end
            PSNR = psnr(deblurredImg, Orgimg);
            app.LWPSNR.Value = sprintf('%.2f', PSNR);
            MSE = mean(mean(mean((deblurredImg - Orgimg).^2)));
            app.LMMSE.Value = sprintf('%.5f', MSE);


        end

        % Button pushed function: BlurButton
        function BlurButtonPushed(app, event)
              % Check which checkbox is selected
            if app.GaussianButton.Value

                sigma = app.sigmaSpinner.Value;
                hsize = app.hsizeSpinner.Value;
               
                %Blurred image
                grayscaleImage2 = imread("cameraman.tif");
                rgbImage2 = cat(3, grayscaleImage2, grayscaleImage2, grayscaleImage2);
                PSF = fspecial('gaussian',hsize,sigma);
                Idouble = im2double(rgbImage2);
                blurredImage = imfilter(Idouble,PSF,'conv','circular');
                
                app.BlurredImg.ImageSource = blurredImage;

            elseif app.MotionBlurButton.Value
               
                theta = app.thetaSpinner.Value;
                length = app.lengthSpinner.Value;

                grayscaleImage2 = imread("cameraman.tif");
                rgbImage2 = cat(3, grayscaleImage2, grayscaleImage2, grayscaleImage2);
                PSF = fspecial('motion',theta,length);
                Idouble = im2double(rgbImage2);
                blurredImage = imfilter(Idouble,PSF,'conv','circular');
                
                app.BlurredImg.ImageSource = blurredImage;
               
            else
                disp('No blur option selected');
            end

            originalImage = im2double(app.OrgImg.ImageSource);
            
            %calculate PSNR
            BlurImgPSNR = psnr(blurredImage, originalImage);
            MSE = mean(mean(mean((blurredImage - originalImage).^2)));
            %convert to number to display
            app.BlurredPSNR.Value = sprintf('%.2f',BlurImgPSNR);
            app.BlurredMSE.Value = sprintf('%.5f', MSE);
        end

        % Button pushed function: BlindDeconvolution_deblurButton
        function BlindDeconvolution_deblurButtonPushed(app, event)
            if app.GaussianButton.Value 
                sigma = app.sigmaSpinner.Value;
                hsize = app.hsizeSpinner.Value;
        
                % Perform deblurring 
                PSF = fspecial('motion',hsize,sigma);

            else
                theta = app.thetaSpinner.Value;
                length = app.lengthSpinner.Value;

                PSF = fspecial('motion',theta,length);
            end

            blurred_image = app.BlurredImg.ImageSource;
             
            %deconvblind 
            iterations = 50;
            [deblurred_blind, estimated_PSF] = deconvblind(blurred_image, PSF, iterations);
            imshow(deblurred_blind, 'Parent',app.BlindAxes);

            originalImage = im2double(app.OrgImg.ImageSource);
            
            %calculate PSNR
            BlurImgPSNR = psnr(deblurred_blind, originalImage);
            MSE = mean(mean(mean((deblurred_blind - originalImage).^2)));
            %convert to number to display
            app.BlindPSNR.Value = sprintf('%.2f', BlurImgPSNR);
            app.BlindMSE.Value = sprintf('%.5f', MSE);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WeinerAxes = uiaxes(app.UIFigure);
            title(app.WeinerAxes, 'Weiner Filter')
            zlabel(app.WeinerAxes, 'Z')
            app.WeinerAxes.XTick = [];
            app.WeinerAxes.YTick = [];
            app.LMAxes = uiaxes(app.UIFigure);
            title(app.LMAxes, 'Landweber Algorithm')
            app.LMAxes.XTick = [];
            app.LMAxes.YTick = [];
            app.BlindAxes = uiaxes(app.UIFigure);
            title(app.BlindAxes, 'Blind Deconvolution')
            app.BlindAxes.XTick = [];
            app.BlindAxes.YTick = [];
            app.WNR_deblurButton = uibutton(app.UIFigure, 'push');
            app.WNR_deblurButton.ButtonPushedFcn = createCallbackFcn(app, @WNR_deblurButtonPushed, true);
            app.WNR_deblurButton.Text = 'WNR_deblur';
            app.OrgImg = uiimage(app.UIFigure);
            app.OrgImg.ImageSource = fullfile(pathToMLAPP, 'cameraman.png');
            app.BlurredImg = uiimage(app.UIFigure);
            app.LM_deblurButton = uibutton(app.UIFigure, 'push');
            app.LM_deblurButton.ButtonPushedFcn = createCallbackFcn(app, @LM_deblurButtonPushed, true);
            app.LM_deblurButton.Text = 'LM_deblur';
            app.PSNRTextAreaLabel = uilabel(app.UIFigure);
            app.PSNRTextAreaLabel.Text = 'PSNR';
            app.WeinerPSNR = uitextarea(app.UIFigure);
            app.PSNRTextArea_2Label = uilabel(app.UIFigure);
            app.PSNRTextArea_2Label.Text = 'PSNR';
            app.LWPSNR = uitextarea(app.UIFigure);
            app.PSNRTextArea_3Label = uilabel(app.UIFigure);
            app.PSNRTextArea_3Label.Text = 'PSNR';
            app.BlindPSNR = uitextarea(app.UIFigure);
            app.MSETextAreaLabel = uilabel(app.UIFigure);
            app.MSETextAreaLabel.Text = 'MSE';
            app.WeinerMSE = uitextarea(app.UIFigure);
            app.MSETextArea_2Label = uilabel(app.UIFigure);
            app.MSETextArea_2Label.Text = 'MSE';
            app.LMMSE = uitextarea(app.UIFigure);
            app.MSETextArea_3Label = uilabel(app.UIFigure);
            app.MSETextArea_3Label.Text = 'MSE';
            app.BlindMSE = uitextarea(app.UIFigure);
            app.BlindDeconvolution_deblurButton = uibutton(app.UIFigure, 'push');
            app.BlindDeconvolution_deblurButton.ButtonPushedFcn = createCallbackFcn(app, @BlindDeconvolution_deblurButtonPushed, true);
            app.BlindDeconvolution_deblurButton.Text = 'Blind Deconvolution_deblur';
            app.BlurTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.BlurTypeButtonGroup.Title = 'Blur Type';
            app.GaussianButton = uiradiobutton(app.BlurTypeButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.GaussianButton.Value = true;
            app.MotionBlurButton = uiradiobutton(app.BlurTypeButtonGroup);
            app.MotionBlurButton.Text = 'Motion Blur';
            app.sigmaSpinnerLabel = uilabel(app.BlurTypeButtonGroup);
            app.sigmaSpinnerLabel.Text = 'sigma:';
            app.sigmaSpinner = uispinner(app.BlurTypeButtonGroup);
            app.lengthSpinnerLabel = uilabel(app.BlurTypeButtonGroup);
            app.lengthSpinnerLabel.Text = 'length';
            app.lengthSpinner = uispinner(app.BlurTypeButtonGroup);
            app.thetaSpinnerLabel = uilabel(app.BlurTypeButtonGroup);
            app.thetaSpinnerLabel.Text = 'theta';
            app.thetaSpinner = uispinner(app.BlurTypeButtonGroup);
            app.hsizeSpinnerLabel = uilabel(app.BlurTypeButtonGroup);
            app.hsizeSpinnerLabel.Text = 'hsize';
            app.hsizeSpinner = uispinner(app.BlurTypeButtonGroup);
            app.BlurButton = uibutton(app.UIFigure, 'push');
            app.BlurButton.ButtonPushedFcn = createCallbackFcn(app, @BlurButtonPushed, true);
            app.BlurButton.Text = 'Blur';
            app.IterationSpinnerLabel = uilabel(app.UIFigure);
            app.IterationSpinnerLabel.Text = 'Iteration';
            app.IterationSpinner = uispinner(app.UIFigure);
            app.StepSizeSpinnerLabel = uilabel(app.UIFigure);
            app.StepSizeSpinnerLabel.Text = 'StepSize';
            app.StepSizeSpinner = uispinner(app.UIFigure);
            app.PSNRTextAreaLabel_2 = uilabel(app.UIFigure);
            app.PSNRTextAreaLabel_2.Text = 'PSNR';
            app.BlurredPSNR = uitextarea(app.UIFigure);
            app.MSETextAreaLabel_2 = uilabel(app.UIFigure);
            app.MSETextAreaLabel_2.Text = 'MSE';
            app.BlurredMSE = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalProject455

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end